classdef GraphicalResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InductionMotorAnalyisisUIFigure  matlab.ui.Figure
    end


    methods (Access = private)
    
        function updateplot(app) %Leave them commented, I use them as reference.
            % Get Table UI component data
          %  t = app.UITable.DisplayData;            
            % Plot modified data
           % x2 = t.Age;
            %y2 = t.BloodPressure(:,2);
            %plot(app.StatorCurrents,x2,y2,'-o');
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %Will center the gui in the desktopscreen
            movegui('center')
            %This part allows us to get the graphs from the test model.
            simout=sim('RTinductionmotorJan16.slx');
            %Creates 3 Subdivisions
            tiledlayout(3,1)

            %First plot
            plot(nexttile,simout.i_dr_output.Time,simout.i_dr_output.Data,simout.i_qr_output.Time,simout.i_qr_output.Data);
            title('Stator Current')
            legend({'stator current/i-qs','stator current/i-ds'})
            xlabel('T(s)')
            ylabel('Amperes')
            
            
            plot(nexttile, simout.Torque_output.Time,simout.Torque_output.Data);
            title('Electro-Magnetic Torque')
            xlabel('T(s)')
            ylabel('Nm')
            
            plot(nexttile,simout.i_ds_output.Time,simout.i_ds_output.Data,simout.i_qs_output.Time,simout.i_qs_output.Data);
            title('Rotor Current')
            legend({'rotor current/i-rs','rotor current/i-dr'})
            xlabel('T(s)')
            ylabel('Amperes')

        end

        % Callback function
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end

        % Callback function
        function ExitButtonPushed(app, event)
            close(app.InductionMotorAnalyisisUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InductionMotorAnalyisisUIFigure = uifigure('Visible', 'off');
            app.InductionMotorAnalyisisUIFigure.Name = 'Induction Motor Analyisis';
            % Show the figure after all components are created
            app.InductionMotorAnalyisisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraphicalResults

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InductionMotorAnalyisisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InductionMotorAnalyisisUIFigure)
        end
    end
end