classdef GraphicalResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InductionMotorAnalyisisUIFigure  matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        ExportMenu                 matlab.ui.container.Menu
        DataMenu                   matlab.ui.container.Menu
        ExcelMenu                  matlab.ui.container.Menu
        TextfileMenu               matlab.ui.container.Menu
        ImagesMenu                 matlab.ui.container.Menu
        ElectroMagneticTorqueMenu  matlab.ui.container.Menu
        RotorCurrentMenu           matlab.ui.container.Menu
        StatorCurrentMenu          matlab.ui.container.Menu
        AllMenu                    matlab.ui.container.Menu
        TabGroup                   matlab.ui.container.TabGroup
        MotorTab                   matlab.ui.container.Tab
        GridLayout                 matlab.ui.container.GridLayout
        Stator                     matlab.ui.control.UIAxes
        Rotor                      matlab.ui.control.UIAxes
        Torque                     matlab.ui.control.UIAxes
        PWMTab                     matlab.ui.container.Tab
    end

    
    properties (Access = private)
        callingApp % object of the app that calls this activity to exchange info
        fromSimulation
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, simulated)
            movegui('center');
            
            app.callingApp = mainApp;
            app.fromSimulation = simulated;
            
            %Creates 3 Subdivisions
            tiledlayout(3,1)

            %First plot
            fig1=uifigure;
            ax1=uiaxes(fig1);
            x=(app.fromSimulation.i_dr_output.Time);
            y=(vapp.fromSimulation.i_dr_output.Data);
            plot(ax1,x,y)
            
%              app.Stator.plot(app.fromSimulation.i_dr_output.Time,app.fromSimulation.i_dr_output.Data,app.fromSimulation.i_qr_output.Time,app.fromSimulation.i_qr_output.Data);
%            % app.Stator.plot(nexttile,app.fromSimulation.i_dr_output.Time,app.fromSimulation.i_dr_output.Data,app.fromSimulation.i_qr_output.Time,app.fromSimulation.i_qr_output.Data);
%              app.Stator.title('Stator Current')
%              app.Stator.legend({'stator current/i-qs','stator current/i-ds'})
%              app.Stator.xlabel('t(s)')
%              app.Stator.ylabel('Amps')
%             
%             
%             plot(nexttile, app.fromSimulation.Torque_output.Time,app.fromSimulation.Torque_output.Data);
%             title('Electro-Magnetic Torque')
%             xlabel('t(s)')
%             ylabel('Nm')
%             
%             plot(nexttile,app.fromSimulation.i_ds_output.Time,app.fromSimulation.i_ds_output.Data,app.fromSimulation.i_qs_output.Time,app.fromSimulation.i_qs_output.Data);
%             title('Rotor Current')
%             legend({'rotor current/i-rs','rotor current/i-dr'})
%             xlabel('t(s)')
%             ylabel('Amps')

        end

        % Callback function
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end

        % Callback function
        function ExitButtonPushed(app, event)
            close(app.InductionMotorAnalyisisUIFigure)
        end

        % Close request function: InductionMotorAnalyisisUIFigure
        function InductionMotorAnalyisisUIFigureCloseRequest(app, event)
            app.callingApp.GraphicalResultsButton.Enable = 'on';
            delete(app)      
        end

        % Menu selected function: DataMenu
        function DataMenuSelected(app, event)
%Keep empty
        end

        % Menu selected function: ImagesMenu
        function ImagesMenuSelected(app, event)
%Keep empty
        end

        % Menu selected function: ExcelMenu
        function ExcelMenuSelected(app, event)
                        %Here are created the arrays holding the information for the
            %table that will be exported 
            %Dr section
            time_dr =  app.fromSimulation.i_dr_output.Time;
            dr_amp =  app.fromSimulation.i_dr_output.Data;
            % Qr section
            time_qr =  app.fromSimulation.i_qr_output.Time;
            qr_amp =  app.fromSimulation.i_qr_output.Data;
                        
            %torque section for export
            time_torque =  app.fromSimulation.Torque_output.Time;
            torque_amp =  app.fromSimulation.Torque_output.Data;
                        
            %i_ds section for excel
            time_i_ds =  app.fromSimulation.i_ds_output.Time;
            i_ds_amp =  app.fromSimulation.i_ds_output.Data;
            % i_qs section
            time_i_qs =  app.fromSimulation.i_qs_output.Time;
            i_qs_amp =  app.fromSimulation.i_qs_output.Data;   
            t=now %gets time in long 
            d = datetime(floor(t),'ConvertFrom','datenum')%converts it to date and time. floor only gets the date only
            name=strcat('Data-',datestr(d),'.xlsx')
            Table1 = table(time_dr,dr_amp,time_qr, qr_amp,time_torque, torque_amp,time_i_ds,i_ds_amp,time_i_qs,i_qs_amp)
            writetable(Table1,name,'WriteRowNames',true) 
        end

        % Menu selected function: TextfileMenu
        function TextfileMenuSelected(app, event)
                        %Here are created the arrays holding the information for the
            %table that will be exported 
            %Dr section
            time_dr =  app.fromSimulation.i_dr_output.Time;
            dr_amp =  app.fromSimulation.i_dr_output.Data;
            % Qr section
            time_qr =  app.fromSimulation.i_qr_output.Time;
            qr_amp =  app.fromSimulation.i_qr_output.Data;
                        
            %torque section for export
            time_torque =  app.fromSimulation.Torque_output.Time;
            torque_amp =  app.fromSimulation.Torque_output.Data;
                        
            %i_ds section for excel
            time_i_ds =  app.fromSimulation.i_ds_output.Time;
            i_ds_amp =  app.fromSimulation.i_ds_output.Data;
            % i_qs section
            time_i_qs =  app.fromSimulation.i_qs_output.Time;
            i_qs_amp =  app.fromSimulation.i_qs_output.Data;   
            t=now %gets time in long 
            d = datetime(floor(t),'ConvertFrom','datenum')%converts it to date and time. floor only gets the date only
            name=strcat('Data-',datestr(d),'.txt')
            Table1 = table(time_dr,dr_amp,time_qr, qr_amp,time_torque, torque_amp,time_i_ds,i_ds_amp,time_i_qs,i_qs_amp)
            writetable(Table1,name,'WriteRowNames',true) 
        end

        % Menu selected function: ElectroMagneticTorqueMenu
        function ElectroMagneticTorqueMenuSelected(app, event)
            getfigurefordesktopclient
        end

        % Menu selected function: RotorCurrentMenu
        function RotorCurrentMenuSelected(app, event)
            
        end

        % Menu selected function: StatorCurrentMenu
        function StatorCurrentMenuSelected(app, event)
            
        end

        % Menu selected function: AllMenu
        function AllMenuSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InductionMotorAnalyisisUIFigure = uifigure('Visible', 'off');
            app.InductionMotorAnalyisisUIFigure.Name = 'Induction Motor Analyisis';
            app.InductionMotorAnalyisisUIFigure.CloseRequestFcn = createCallbackFcn(app, @InductionMotorAnalyisisUIFigureCloseRequest, true);
            app.Menu = uimenu(app.InductionMotorAnalyisisUIFigure);
            app.Menu.Text = 'Menu';
            app.ExportMenu = uimenu(app.Menu);
            app.ExportMenu.Text = 'Export';
            app.DataMenu = uimenu(app.ExportMenu);
            app.DataMenu.MenuSelectedFcn = createCallbackFcn(app, @DataMenuSelected, true);
            app.DataMenu.Text = 'Data';
            app.ExcelMenu = uimenu(app.DataMenu);
            app.ExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcelMenuSelected, true);
            app.ExcelMenu.Text = 'Excel';
            app.TextfileMenu = uimenu(app.DataMenu);
            app.TextfileMenu.MenuSelectedFcn = createCallbackFcn(app, @TextfileMenuSelected, true);
            app.TextfileMenu.Text = 'Text file';
            app.ImagesMenu = uimenu(app.ExportMenu);
            app.ImagesMenu.MenuSelectedFcn = createCallbackFcn(app, @ImagesMenuSelected, true);
            app.ImagesMenu.Text = 'Images';
            app.ElectroMagneticTorqueMenu = uimenu(app.ImagesMenu);
            app.ElectroMagneticTorqueMenu.MenuSelectedFcn = createCallbackFcn(app, @ElectroMagneticTorqueMenuSelected, true);
            app.ElectroMagneticTorqueMenu.Text = 'Electro-Magnetic Torque';
            app.RotorCurrentMenu = uimenu(app.ImagesMenu);
            app.RotorCurrentMenu.MenuSelectedFcn = createCallbackFcn(app, @RotorCurrentMenuSelected, true);
            app.RotorCurrentMenu.Text = 'Rotor Current';
            app.StatorCurrentMenu = uimenu(app.ImagesMenu);
            app.StatorCurrentMenu.MenuSelectedFcn = createCallbackFcn(app, @StatorCurrentMenuSelected, true);
            app.StatorCurrentMenu.Text = 'Stator Current';
            app.AllMenu = uimenu(app.ImagesMenu);
            app.AllMenu.MenuSelectedFcn = createCallbackFcn(app, @AllMenuSelected, true);
            app.AllMenu.Text = 'All';
            app.TabGroup = uitabgroup(app.InductionMotorAnalyisisUIFigure);
            app.MotorTab = uitab(app.TabGroup);
            app.MotorTab.Title = 'Motor';
            app.GridLayout = uigridlayout(app.MotorTab);
            app.Stator = uiaxes(app.GridLayout);
            title(app.Stator, 'Title')
            xlabel(app.Stator, 'X')
            ylabel(app.Stator, 'Y')
            app.Rotor = uiaxes(app.GridLayout);
            title(app.Rotor, 'Title')
            xlabel(app.Rotor, 'X')
            ylabel(app.Rotor, 'Y')
            app.Torque = uiaxes(app.GridLayout);
            title(app.Torque, 'Title')
            xlabel(app.Torque, 'X')
            ylabel(app.Torque, 'Y')
            app.PWMTab = uitab(app.TabGroup);
            app.PWMTab.Title = 'PWM';
            % Show the figure after all components are created
            app.InductionMotorAnalyisisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraphicalResults(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InductionMotorAnalyisisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InductionMotorAnalyisisUIFigure)
        end
    end
end