classdef VFLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VFLaboratoryUIFigure         matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MotorParametersTab           matlab.ui.container.Tab
        GridLayout5                  matlab.ui.container.GridLayout
        Panel                        matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        FrequencySlider_2Label       matlab.ui.control.Label
        FrequencySlider              matlab.ui.control.Slider
        EditFrequence                matlab.ui.control.EditField
        PolesSpinner_2Label          matlab.ui.control.Label
        PolesSpinner                 matlab.ui.control.Spinner
        Image_2                      matlab.ui.control.Image
        PHASEINDUCTIONMOTORLabel_2   matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        SetParametersButton          matlab.ui.control.Button
        PWMInverterPanel_7           matlab.ui.container.Panel
        GridLayout10_2               matlab.ui.container.GridLayout
        VdcKnob                      matlab.ui.control.Knob
        mfindexEditField             matlab.ui.control.EditField
        mfindexEditFieldLabel        matlab.ui.control.Label
        maindexEditField             matlab.ui.control.EditField
        maindexEditFieldLabel        matlab.ui.control.Label
        VDCEditFieldLabel            matlab.ui.control.Label
        VdcEditField                 matlab.ui.control.EditField
        ParametersPanel              matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        RsEditFieldLabel             matlab.ui.control.Label
        RsEditField                  matlab.ui.control.NumericEditField
        XsEditFieldLabel             matlab.ui.control.Label
        XsEditField                  matlab.ui.control.NumericEditField
        XmEditFieldLabel             matlab.ui.control.Label
        XmEditField                  matlab.ui.control.NumericEditField
        XrEditFieldLabel             matlab.ui.control.Label
        XrEditField                  matlab.ui.control.NumericEditField
        RrEditFieldLabel             matlab.ui.control.Label
        RrEditField                  matlab.ui.control.NumericEditField
        MotorLoadPanel_2             matlab.ui.container.Panel
        GridLayout16                 matlab.ui.container.GridLayout
        LoadKnob_2Label              matlab.ui.control.Label
        LoadTorqueKnob               matlab.ui.control.Knob
        NmLabel                      matlab.ui.control.Label
        LoadTorqueEditField          matlab.ui.control.EditField
        LampLabel                    matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        StartButton                  matlab.ui.control.Button
        PresetMotorButton            matlab.ui.control.Button
        EraseAllButton               matlab.ui.control.Button
        SetParametersMessageBox      matlab.ui.control.EditField
        HelpButton                   matlab.ui.control.Button
        GraphicalResultsTab          matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        ExportDropDownLabel          matlab.ui.control.Label
        ExportDropDown               matlab.ui.control.DropDown
        TabGroup2                    matlab.ui.container.TabGroup
        GeneralResultsTab            matlab.ui.container.Tab
        GridLayout17                 matlab.ui.container.GridLayout
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes2_2                    matlab.ui.control.UIAxes
        UIAxes2_3                    matlab.ui.control.UIAxes
        ElectroMagneticTorqueTab     matlab.ui.container.Tab
        GridLayout13                 matlab.ui.container.GridLayout
        UIAxes_EM_torque             matlab.ui.control.UIAxes
        SpeedTab                     matlab.ui.container.Tab
        GridLayout14                 matlab.ui.container.GridLayout
        UIAxes_speed_vs_torque       matlab.ui.control.UIAxes
        PWMTab                       matlab.ui.container.Tab
        GridLayout15                 matlab.ui.container.GridLayout
        UIAxes_pwm                   matlab.ui.control.UIAxes
        VoltageTab                   matlab.ui.container.Tab
        ReadMeTab                    matlab.ui.container.Tab
        GridLayout9                  matlab.ui.container.GridLayout
        ModeTextAreaLabel            matlab.ui.control.Label
        ModeTextArea                 matlab.ui.control.TextArea
        MotorTextAreaLabel           matlab.ui.control.Label
        MotorTextArea                matlab.ui.control.TextArea
        PWMTextAreaLabel             matlab.ui.control.Label
        PWMTextArea                  matlab.ui.control.TextArea
        MotorTextArea_2Label         matlab.ui.control.Label
        MotorTextArea_2              matlab.ui.control.TextArea
        RelevantInformationTextAreaLabel  matlab.ui.control.Label
        RelevantInformationTextArea  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        callingApp % Description
        simulated
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
           
            app.callingApp = mainApp;
            movegui(app.VFLaboratoryUIFigure,'center');
            load_system('simulation\RTInductionMotorFeb21.slx');   %loads the system into temp memory
            % so that the command 'bdroot' returns the last Simulink model loaded
            set_param([bdroot,'/SourceSelect'],'Value',num2str(1)); 
            
        end

        % Value changing function: PolesSpinner
        function PolesSpinnerValueChanging(app, event)
            changingValue = event.Value; % reads the value changed in the event happening
            
            polesSpinner = num2str(changingValue); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
            
            set_param([bdroot,'/Motor Model/Poles'],'Value', polesSpinner);
        end

        % Value changing function: FrequencySlider
        function FrequencySliderValueChanging(app, event)
            changingValue = event.Value; % reads the value changed in the event happening
            
            % We choose FreqValueChanging instead of FreqValueChanged
            % because it is more precise (i.e. it reacts to the continuous
            % variation of the slider)
            
            freqSlider = num2str(round(changingValue)); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
            % round() avoids gettings values like 45.4168751
            app.EditFrequence.Value = freqSlider;        
            set_param([bdroot,'/Motor Model/Freq'],'Value', freqSlider);

        end

        % Callback function: EditFrequence, EditFrequence
        function EditFrequenceValueChanged(app, event)
            valueEdit = app.EditFrequence.Value;
            app.FrequencySlider.Value=str2double(valueEdit);          
            set_param([bdroot,'/Motor Model/Freq'],'Value', num2str(valueEdit));
            

        end

        % Value changed function: VdcKnob
        function VdcKnobValueChanging(app, event)
            changingValue = event.Value;
            
            voltKnob = num2str(changingValue); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
      
            set_param([bdroot,'/PWM + inverter/Vdc'],'Value', voltKnob);
                  app.VdcEditField.Value = num2str(round(changingValue));            

        end

        % Callback function
        function VdcEditFieldValueChanged(app, event)
            valueEdit = app.VdcEditField.Value;
           
            set_param([bdroot,'/PWM + inverter/Vdc'],'Value', num2str(valueEdit));
            
            app.VdcKnob.Value=str2double(valueEdit);
        end

        % Callback function: mfindexEditField, mfindexEditField
        function mfindexEditFieldValueChanged(app, event)
            valueEdit = app.mfindexEditField.Value;
           
            set_param([bdroot,'/PWM + inverter/Three-phase PWM/Triangle Generator'],'Freq', num2str(valueEdit));
            
        end

        % Callback function: maindexEditField, maindexEditField
        function maindexEditFieldValueChanged(app, event)
            valueEdit = app.maindexEditField.Value;
           
            set_param([bdroot,'/Ma'],'Value', num2str(valueEdit));
            
        end

        % Value changing function: LoadTorqueKnob
        function LoadTorqueKnobValueChanging(app, event)
            changingValue = event.Value;
            
            loadKnob = num2str(round(changingValue)); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
              
            set_param([bdroot,'/Motor Model/Input Torque'],'Value', loadKnob);
           
            app.LoadTorqueEditField.Value =  loadKnob;
        end

        % Callback function: LoadTorqueEditField, LoadTorqueEditField
        function LoadTorqueEditFieldValueChanged(app, event)
            valueEdit = app.LoadTorqueEditField.Value;
           
            set_param([bdroot,'/Motor Model/Input Torque'],'Value', num2str(valueEdit));
            
            app.LoadTorqueKnob.Value=str2double(valueEdit);
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            %app.SetParametersButton.Enable = 'off';        
            
                       
            Rs = num2str(app.RsEditField.Value);
            Ls = num2str(app.XsEditField.Value);
            Rr = num2str(app.RrEditField.Value);           
            Lr = num2str(app.XrEditField.Value);
            Lm = num2str(app.XmEditField.Value);
            
            set_param([bdroot,'/Motor Model/Rs (ohms)'],'Value',Rs);
            set_param([bdroot,'/Motor Model/Ls (H)'],'Value',Ls); 
            set_param([bdroot,'/Motor Model/Rr (ohms)'],'Value',Rr);
            set_param([bdroot,'/Motor Model/Lr (H)'],'Value',Lr);
            set_param([bdroot,'/Motor Model/Lm (H)'],'Value',Lm);
            
            save_system('simulation\RTInductionMotorFeb21.slx');
            
            app.SetParametersMessageBox.Value='Values sent to model';
            %app.SetParametersMessageBox.Enable='false';
            app.SetParametersMessageBox.Visible='true';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable ='off';
            %save_system('simulation/RTInductionMotorFeb21.slx');
            save_system('simulation\RTInductionMotorFeb21.slx');
            app.simulated = sim("simulation\RTInductionMotorFeb21.slx");
      
            if (get_param(bdroot,'SimulationStatus') == 'stopped')
                app.StartButton.Enable ='on';
            end
        end

        % Close request function: VFLaboratoryUIFigure
        function VFLaboratoryUIFigureCloseRequest(app, event)
            app.callingApp.Laboratory2Button.Enable = 'on';
            delete(app)
            
        end

        % Button pushed function: EraseAllButton
        function EraseAllButtonPushed(app, event)
            app.RsEditField.Value=0;
            app.XsEditField.Value=0;
            app.XmEditField.Value=0;
            app.XrEditField.Value=0;
            app.RrEditField.Value=0;
            app.FrequencySlider.Value=0;
            app.LoadTorqueKnob.Value=0;
            app.VrmsGridVKnob.Value=0;
            app.PolesSpinner.Value=0;
            app.VrmsEditField.Value= num2str(0);
            app.LoadTorqueEditField.Value=num2str(0);
            app.SetParametersMessageBox.Visible=false;
            app.mfindexEditField.Value=num2str(0);
            app.maindexEditField.Value=num2str(0);
            app.VdcEditField.Value=num2str(0);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            
        end

        % Button pushed function: PresetMotorButton
        function PresetMotorButtonPushed(app, event)
            app.RsEditField.Value=1;
            app.XsEditField.Value=0.003883;
            app.XmEditField.Value=0.1485;
            app.XrEditField.Value=0.009061;
            app.RrEditField.Value=1.06;
            app.FrequencySlider.Value=60;
            app.PolesSpinner.Value=4;
        end

        % Value changed function: SetParametersMessageBox
        function SetParametersMessageBoxValueChanged(app, event)
            value = app.SetParametersMessageBox.Value;
            
        end

        % Value changed function: VdcEditField
        function VdcEditFieldValueChanged2(app, event)
            value = app.VdcEditField.Value;
            set_param([bdroot,'/PWM + inverter/Vdc'],'Value', num2str(value));
            app.VdcKnob.Value=str2double(value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VFLaboratoryUIFigure = uifigure('Visible', 'off');
            app.VFLaboratoryUIFigure.Name = 'VF Laboratory';
            app.VFLaboratoryUIFigure.CloseRequestFcn = createCallbackFcn(app, @VFLaboratoryUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.VFLaboratoryUIFigure);
            app.MotorParametersTab = uitab(app.TabGroup);
            app.MotorParametersTab.Title = 'Motor Parameters';
            app.GridLayout5 = uigridlayout(app.MotorParametersTab);
            app.Panel = uipanel(app.GridLayout5);
            app.GridLayout7 = uigridlayout(app.Panel);
            app.FrequencySlider_2Label = uilabel(app.GridLayout7);
            app.FrequencySlider_2Label.Text = 'Frequency ';
            app.FrequencySlider = uislider(app.GridLayout7);
            app.FrequencySlider.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderValueChanging, true);
            app.FrequencySlider.Value = 30;
            app.EditFrequence = uieditfield(app.GridLayout7, 'text');
            app.EditFrequence.ValueChangedFcn = createCallbackFcn(app, @EditFrequenceValueChanged, true);
            app.EditFrequence.ValueChangingFcn = createCallbackFcn(app, @EditFrequenceValueChanged, true);
            app.PolesSpinner_2Label = uilabel(app.GridLayout7);
            app.PolesSpinner_2Label.Text = 'Poles';
            app.PolesSpinner = uispinner(app.GridLayout7);
            app.PolesSpinner.ValueChangingFcn = createCallbackFcn(app, @PolesSpinnerValueChanging, true);
            app.PolesSpinner.Limits = [0 Inf];
            app.Image_2 = uiimage(app.GridLayout5);
            app.Image_2.ImageSource = 'EquivalentCircuitDiagram.png';
            app.PHASEINDUCTIONMOTORLabel_2 = uilabel(app.GridLayout5);
            app.PHASEINDUCTIONMOTORLabel_2.Text = '3-PHASE INDUCTION MOTOR';
            app.TextArea = uitextarea(app.GridLayout5);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'* The values shown are pre-set values. They can be changed at any given moment during the lab experiment'};
            app.SetParametersButton = uibutton(app.GridLayout5, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.PWMInverterPanel_7 = uipanel(app.GridLayout5);
            app.PWMInverterPanel_7.Title = 'PWM Inverter';
            app.GridLayout10_2 = uigridlayout(app.PWMInverterPanel_7);
            app.VdcKnob = uiknob(app.GridLayout10_2, 'continuous');
            app.VdcKnob.Limits = [0 400];
            app.VdcKnob.ValueChangedFcn = createCallbackFcn(app, @VdcKnobValueChanging, true);
            app.mfindexEditField = uieditfield(app.GridLayout10_2, 'text');
            app.mfindexEditField.ValueChangedFcn = createCallbackFcn(app, @mfindexEditFieldValueChanged, true);
            app.mfindexEditField.ValueChangingFcn = createCallbackFcn(app, @mfindexEditFieldValueChanged, true);
            app.mfindexEditFieldLabel = uilabel(app.GridLayout10_2);
            app.mfindexEditFieldLabel.Text = 'mf index';
            app.maindexEditField = uieditfield(app.GridLayout10_2, 'text');
            app.maindexEditField.ValueChangedFcn = createCallbackFcn(app, @maindexEditFieldValueChanged, true);
            app.maindexEditField.ValueChangingFcn = createCallbackFcn(app, @maindexEditFieldValueChanged, true);
            app.maindexEditFieldLabel = uilabel(app.GridLayout10_2);
            app.maindexEditFieldLabel.Text = 'ma index';
            app.VDCEditFieldLabel = uilabel(app.GridLayout10_2);
            app.VDCEditFieldLabel.Text = 'VDC';
            app.VdcEditField = uieditfield(app.GridLayout10_2, 'text');
            app.VdcEditField.ValueChangedFcn = createCallbackFcn(app, @VdcEditFieldValueChanged2, true);
            app.ParametersPanel = uipanel(app.GridLayout5);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout6 = uigridlayout(app.ParametersPanel);
            app.RsEditFieldLabel = uilabel(app.GridLayout6);
            app.RsEditFieldLabel.Text = 'Rs';
            app.RsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XsEditFieldLabel = uilabel(app.GridLayout6);
            app.XsEditFieldLabel.Text = 'Xs';
            app.XsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XmEditFieldLabel = uilabel(app.GridLayout6);
            app.XmEditFieldLabel.Text = 'Xm';
            app.XmEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XrEditFieldLabel = uilabel(app.GridLayout6);
            app.XrEditFieldLabel.Text = 'Xr';
            app.XrEditField = uieditfield(app.GridLayout6, 'numeric');
            app.RrEditFieldLabel = uilabel(app.GridLayout6);
            app.RrEditFieldLabel.Text = 'Rr''';
            app.RrEditField = uieditfield(app.GridLayout6, 'numeric');
            app.MotorLoadPanel_2 = uipanel(app.GridLayout5);
            app.MotorLoadPanel_2.Title = 'Motor Load';
            app.GridLayout16 = uigridlayout(app.MotorLoadPanel_2);
            app.LoadKnob_2Label = uilabel(app.GridLayout16);
            app.LoadKnob_2Label.Text = 'Load';
            app.LoadTorqueKnob = uiknob(app.GridLayout16, 'continuous');
            app.LoadTorqueKnob.ValueChangingFcn = createCallbackFcn(app, @LoadTorqueKnobValueChanging, true);
            app.NmLabel = uilabel(app.GridLayout16);
            app.NmLabel.Text = 'N*m';
            app.LoadTorqueEditField = uieditfield(app.GridLayout16, 'text');
            app.LoadTorqueEditField.ValueChangedFcn = createCallbackFcn(app, @LoadTorqueEditFieldValueChanged, true);
            app.LoadTorqueEditField.ValueChangingFcn = createCallbackFcn(app, @LoadTorqueEditFieldValueChanged, true);
            app.LampLabel = uilabel(app.GridLayout16);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.GridLayout16);
            app.StartButton = uibutton(app.GridLayout5, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PresetMotorButton = uibutton(app.GridLayout5, 'push');
            app.PresetMotorButton.ButtonPushedFcn = createCallbackFcn(app, @PresetMotorButtonPushed, true);
            app.PresetMotorButton.Text = 'Preset Motor ';
            app.EraseAllButton = uibutton(app.GridLayout5, 'push');
            app.EraseAllButton.ButtonPushedFcn = createCallbackFcn(app, @EraseAllButtonPushed, true);
            app.EraseAllButton.Text = 'Erase All';
            app.SetParametersMessageBox = uieditfield(app.GridLayout5, 'text');
            app.SetParametersMessageBox.ValueChangedFcn = createCallbackFcn(app, @SetParametersMessageBoxValueChanged, true);
            app.SetParametersMessageBox.Editable = 'off';
            app.SetParametersMessageBox.Enable = 'off';
            app.SetParametersMessageBox.Visible = 'off';
            app.HelpButton = uibutton(app.GridLayout5, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Icon = 'help-sign-icon-simple-question-symbol-MT5NW0.jpg';
            app.HelpButton.Text = 'Help';
            app.GraphicalResultsTab = uitab(app.TabGroup);
            app.GraphicalResultsTab.Title = 'Graphical Results';
            app.GridLayout4 = uigridlayout(app.GraphicalResultsTab);
            app.ExportDropDownLabel = uilabel(app.GridLayout4);
            app.ExportDropDownLabel.Text = 'Export';
            app.ExportDropDown = uidropdown(app.GridLayout4);
            app.ExportDropDown.Items = {'Data (Excel)', 'Data (txt)', 'Images (pdf)', 'Images (word)'};
            app.ExportDropDown.Value = 'Data (Excel)';
            app.TabGroup2 = uitabgroup(app.GridLayout4);
            app.GeneralResultsTab = uitab(app.TabGroup2);
            app.GeneralResultsTab.Title = 'General Results';
            app.GridLayout17 = uigridlayout(app.GeneralResultsTab);
            app.UIAxes = uiaxes(app.GridLayout17);
            title(app.UIAxes, 'Voltage')
            xlabel(app.UIAxes, 'T(s)')
            ylabel(app.UIAxes, 'V')
            app.UIAxes2 = uiaxes(app.GridLayout17);
            title(app.UIAxes2, 'Current')
            xlabel(app.UIAxes2, 'T(s)')
            ylabel(app.UIAxes2, 'A')
            app.UIAxes2_2 = uiaxes(app.GridLayout17);
            title(app.UIAxes2_2, 'Speed')
            xlabel(app.UIAxes2_2, 'T(s)')
            ylabel(app.UIAxes2_2, 'RPM')
            app.UIAxes2_3 = uiaxes(app.GridLayout17);
            title(app.UIAxes2_3, 'Torque')
            xlabel(app.UIAxes2_3, 'T(s)')
            ylabel(app.UIAxes2_3, 'N.m')
            app.ElectroMagneticTorqueTab = uitab(app.TabGroup2);
            app.ElectroMagneticTorqueTab.Title = 'Electro-Magnetic Torque';
            app.GridLayout13 = uigridlayout(app.ElectroMagneticTorqueTab);
            app.UIAxes_EM_torque = uiaxes(app.GridLayout13);
            title(app.UIAxes_EM_torque, 'Electro-Magnetic Torque')
            xlabel(app.UIAxes_EM_torque, 'Time (s)')
            ylabel(app.UIAxes_EM_torque, 'Amps')
            app.SpeedTab = uitab(app.TabGroup2);
            app.SpeedTab.Title = 'Speed ';
            app.GridLayout14 = uigridlayout(app.SpeedTab);
            app.UIAxes_speed_vs_torque = uiaxes(app.GridLayout14);
            title(app.UIAxes_speed_vs_torque, 'Speed vs Torque')
            xlabel(app.UIAxes_speed_vs_torque, 'N.m')
            ylabel(app.UIAxes_speed_vs_torque, 'rpm')
            app.PWMTab = uitab(app.TabGroup2);
            app.PWMTab.Title = 'PWM';
            app.GridLayout15 = uigridlayout(app.PWMTab);
            app.UIAxes_pwm = uiaxes(app.GridLayout15);
            title(app.UIAxes_pwm, 'PWM')
            xlabel(app.UIAxes_pwm, 'Time (s)')
            ylabel(app.UIAxes_pwm, 'volts')
            app.VoltageTab = uitab(app.TabGroup2);
            app.VoltageTab.Title = 'Voltage';
            app.ReadMeTab = uitab(app.TabGroup);
            app.ReadMeTab.Title = 'Read Me';
            app.GridLayout9 = uigridlayout(app.ReadMeTab);
            app.ModeTextAreaLabel = uilabel(app.GridLayout9);
            app.ModeTextAreaLabel.Text = 'Mode';
            app.ModeTextArea = uitextarea(app.GridLayout9);
            app.ModeTextArea.Editable = 'off';
            app.ModeTextArea.Value = {'The mode pannel is composed of two lights: green and red. The green corresponds to the working mode or "on" mode. The purpose of this app is to emulate an electric drive motor. This visual only shows the student if the speed is correct, such that they do not go into generation mode.'};
            app.MotorTextAreaLabel = uilabel(app.GridLayout9);
            app.MotorTextAreaLabel.Text = 'Motor';
            app.MotorTextArea = uitextarea(app.GridLayout9);
            app.MotorTextArea.Editable = 'off';
            app.MotorTextArea.Value = {'In order to get visual results, we first need to inputs the parameters of the motor. Once they have been set and saved, the student will be able to go into the "Graphical Results". If the button "set parameters" has not been set, the latest will not open. (To be implemented once I found out how to send the information to the model).'};
            app.PWMTextAreaLabel = uilabel(app.GridLayout9);
            app.PWMTextAreaLabel.Text = 'PWM';
            app.PWMTextArea = uitextarea(app.GridLayout9);
            app.PWMTextArea.Editable = 'off';
            app.MotorTextArea_2Label = uilabel(app.GridLayout9);
            app.MotorTextArea_2Label.Text = 'Motor';
            app.MotorTextArea_2 = uitextarea(app.GridLayout9);
            app.MotorTextArea_2.Editable = 'off';
            app.MotorTextArea_2.Value = {'In order to get visual results, we first need to inputs the parameters of the motor. Once they have been set and saved, the student will be able to go into the "Graphical Results". If the button "set parameters" has not been set, the latest will not open. (To be implemented once I found out how to send the information to the model).'};
            app.RelevantInformationTextAreaLabel = uilabel(app.GridLayout9);
            app.RelevantInformationTextAreaLabel.Text = 'Relevant Information';
            app.RelevantInformationTextArea = uitextarea(app.GridLayout9);
            app.RelevantInformationTextArea.Editable = 'off';
            app.RelevantInformationTextArea.Value = {'Concordia Security'; ''; '514-848-3717'; ''; 'Dial 1 for urgent situations'; 'Dial 2 for non-urgent situations'; ''; 'In case of emergency, contact 9-1-1'; ''; 'For Licensing questions: inquiry@eruditio.com'};
            % Show the figure after all components are created
            app.VFLaboratoryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VFLab(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VFLaboratoryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VFLaboratoryUIFigure)
        end
    end
end